classdef Coin_Detector_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Coin_Detector           matlab.ui.Figure
        SwitchImageBtn          matlab.ui.control.Button
        CurrencyDropDown        matlab.ui.control.DropDown
        CurrencyLabel           matlab.ui.control.Label
        DetectResultImageTitle  matlab.ui.control.Label
        OriginalImageTitle      matlab.ui.control.Label
        DetectResultLabel       matlab.ui.control.Label
        SelectImageBtn          matlab.ui.control.Button
        DetectResultImage       matlab.ui.control.UIAxes
        OriginalImage           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img              uint8     % Original image
        msk              uint8     % Image after threshold
        mskDil           uint8     % Image after threshold, dilate
        mskDilErd        double    % Image after threshold, dilate and erode
        centroidCoor     double    % Centorid coordinate (coin location)
        componentSizes   double    % Centorid size (coin size)
        coinNum          uint8     % # of coin
        coinFilters      cell      % Filter of each type of coins
        D                double    % Feature of coins (correlation score)
        cls              double    % Classification result
        totalCount       uint8     % Total value of coins
        coinDiameters    double    % Diameter of coins
        coinType         uint8     % # of coin type
        unit             string    % Unit of coin value
        n                uint8     % Current image
    end
    
    methods (Access = private)
        % =============== Main Dececting Functions =============== %
        % Step 1: Find centroid of coins
        function Find_centroid(app)
            % Otsu threshold
            [app.msk, ~] = app.Otsu_threshold(app.img);

            % Dilate
            seDil = strel('disk', 6); % 6
            app.mskDil = imdilate(app.msk, seDil); % hyperparameter

            % Erode
            seEro = strel('disk', 14); % 14
            app.mskDilErd = imerode(app.mskDil, seEro); % hyperparameter
            
            % Get connected components to find centroids of coins
            cc = bwconncomp(app.mskDilErd);
            ctd = regionprops(cc);

            app.coinNum = length(ctd);
            app.centroidCoor = zeros(length(ctd), 2);
            app.componentSizes = zeros(length(ctd), 1);

            for i = 1:length(ctd)
	            % Centroid coordinates
	            app.centroidCoor(i, 1:2) = ceil(ctd(i).Centroid(1:2));
	
	            % Component size
	            app.componentSizes(i, 1) = ctd(i).Area;
            end
        end

        % Step 2: Measure features for each coin using matching filters
        function Measure_feature(app)
            % Set up filter size
            filterSize = floor(size(app.mskDilErd, 1) / 5); % hyperparapeter
            if mod(filterSize, 2) == 0
                filterSize = filterSize + 1;
            end
            filterSizeH = floor(filterSize / 2);

            % Create matching filters
            for i = 1:app.coinType
                app.coinFilters{i} = app.Make_circle_matching_filter(filterSize, app.coinDiameters(i));
            end

            % Evaluate each of the 3 matching filters on each coin to serve as 3 feature measurements
            app.D = zeros(app.coinNum, app.coinType);
            
            for i = 1:app.coinNum
	            % Compute local region
	            localRegion = app.mskDilErd(app.centroidCoor(i, 2) - filterSizeH:app.centroidCoor(i, 2) + filterSizeH, ...
                                            app.centroidCoor(i, 1) - filterSizeH:app.centroidCoor(i, 1) + filterSizeH);
	
	            % Compute correlation score of each coin
                for j = 1:app.coinType
                    app.D(i, j) = corr(app.coinFilters{j}(:), localRegion(:));
                end
            end
        end
        
        % Step 3: Perform k-means clustering of features for classification
        function Kmeans_classification(app)
            % Apply K-means clustering
            rng(0);
            clsInit = kmeans(app.D, app.coinType);

            % Relabel centroid classes (based on average size of objects in each class)
            cSum = zeros(1, app.coinType); % size of each class
            cNum = zeros(1, app.coinType); % # of object in each class

            % Compute average for every classes
            for i = 1:length(clsInit)
                cSum(clsInit(i)) = cSum(clsInit(i)) + app.componentSizes(i);
                cNum(clsInit(i)) = cNum(clsInit(i)) + 1;
            end
            
            classAverageSize = zeros(app.coinType, 1);
            
            for i = 1:app.coinType
	            classAverageSize(i, 1) = cSum(i) / cNum(i);
            end
            
            % Find the sorting indices
            [~, classmap] = sort(classAverageSize);
            
            % Relabel centroid classes
            app.cls = zeros(app.coinNum, 1);
            for i = 1:app.coinNum
	            if clsInit(i) == classmap(1)
		            app.cls(i, 1) = 1;
	            elseif clsInit(i) == classmap(2)
		            app.cls(i, 1) = 2;
                elseif clsInit(i) == classmap(3)
		            app.cls(i, 1) = 3;
                elseif clsInit(i) == classmap(4)
                    app.cls(i, 1) = 4;
	            end
            end
            
            % Compute total count and mark the detected coin on result image
            app.Show_image(app.DetectResultImage, app.img);
            hold(app.DetectResultImage, "on");
            
            app.totalCount = 0;
            for i = 1:app.coinNum
	            [coinValue, ~, ~, ~] = app.Plot_and_count_detect_coins(app.centroidCoor(i, 1), ...
                                                                      app.centroidCoor(i, 2), ...
                                                                      app.cls(i));
	            app.totalCount = app.totalCount + coinValue;
            end
            
            app.DetectResultLabel.Text = "Total: " + num2str(app.totalCount) + " " + app.unit;
        end


        % =============== Helper Functions of Detecting Functions =============== %
        % Apply Otsu threshold to image
        function [msk, thrsh] = Otsu_threshold(~, img)
	        % Define the threshold vale by histogram
	        hist = imhist(img);
	        thrsh = otsuthresh(hist);
	        thrsh = thrsh * 255;
	
	        % Apply threshold to img to create mask
	        msk = img > thrsh;
        end

        % Create matching filter
        function filter = Make_circle_matching_filter(~, filtsize, diameter)
            % Prellocation
            filter = zeros(filtsize, filtsize);
    
            % Define coordinates for the center of filter
            xc = (filtsize + 1) / 2;
            yc = xc;
    
            % Check if each pixel lies in the foreground of the circle
            for r = 1:filtsize
                for c = 1:filtsize
                    % Compute distance to center
                    dist = sqrt((r - yc)^2 + (c - xc)^2);

                    if dist <= (diameter / 2) % within the circle
                        filter(r, c) = 1;
                    end
                end
            end
        end
        
        % Return value and plot the contour of detected coins
        function [coinValue, xPlot, yPlot, col] = Plot_and_count_detect_coins(app, x, y, cls)
            % Define radius, color and value for each type of coins
            if app.CurrencyDropDown.Value == "TWD"
                switch cls
                    case 1
                        r = 20; col = 'r'; coinValue = 1; % hyperparameters
                    case 2
                        r = 23; col = 'g'; coinValue = 5;
                    case 3
                        r = 26; col = 'b'; coinValue = 10;
                    case 4
                        r = 30; col = 'm'; coinValue = 50;
                end
            elseif app.CurrencyDropDown.Value == "USD"
                switch cls
                    case 1
                        r = 22; col = 'r'; coinValue = 10; % hyperparameters
                    case 2
                        r = 30; col = 'g'; coinValue = 5;
                    case 3
                        r = 40; col = 'm'; coinValue = 25;
                end
            end
            
	        % Plot the contour
	        xPlot = x + cos(0:pi/16:2*pi) * r;
	        yPlot = y + sin(0:pi/16:2*pi) * r;
            
            plot(app.DetectResultImage, xPlot, yPlot, col, 'LineWidth', 2);
        end


        % =============== Custom Functions for Application Needs =============== %
        % Make sure foreground is bright and resize the image
        function Image_preprocessing(app)
            % Translate to grayscale image
            if size(app.img, 3) == 3
                gray = rgb2gray(app.img);
            else
                gray = app.img;
            end

            gray = double(gray);

            % Compute background strength (mean of corner)
            pad = 20;
            corners = [gray(1:pad, 1:pad);
                       gray(1:pad, end-pad+1:end);                 
                       gray(end-pad+1:end, 1:pad);                 
                       gray(end-pad+1:end, end-pad+1:end)];
            bgMean = mean(corners(:));

            % Compute foreground strength (using Otsu threshold)
            thrsh = graythresh(uint8(gray));
            thrsh = thrsh * 255;
            grayMsk = gray > thrsh;
            fgMean = mean(gray(grayMsk));

            % Invert image (if background is brighter)
            if bgMean > fgMean
                app.img = imcomplement(uint8(gray));
            end

            % Resize image (to unified the size of coins)
            nr = 246; % hyperparameter
            [r, ~] = size(app.img);
            app.img = imresize(app.img, nr/r);
        end

        % Display image
        function Show_image(~, figure, img)
            % Axes setting
            axis(figure, "tight", "equal");

            % Show image
            imagesc(figure, img);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Defalut currency: TWD
            app.coinDiameters(1) = 30; % 1$
            app.coinDiameters(2) = 36; % 5$
            app.coinDiameters(3) = 45; % 10$
            app.coinDiameters(4) = 50; % 50$
            app.coinType = 4;
            app.unit = "元";

            % Initialize axes
            app.Show_image(app.OriginalImage, []);
            app.Show_image(app.DetectResultImage, []);

            app.n = 0;
        end

        % Button pushed function: SelectImageBtn
        function SelectImageBtnButtonPushed(app, event)
            % Get image file path
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image'}, 'Select image');

            % Canceled
            if isequal(file, 0)
                return;
            end

            % Load image
            imgPath = fullfile(path, file);
            app.img = imread(imgPath);

            % Reset axes
            app.Show_image(app.OriginalImage, []);
            hold(app.DetectResultImage, "off");
            app.Show_image(app.DetectResultImage, []);

            % Detect coins and show before / after
            app.Image_preprocessing();
            app.Show_image(app.OriginalImage, app.img);
            
            app.Find_centroid();
            app.Measure_feature();
            app.Kmeans_classification();

            app.n = 0;
            app.SwitchImageBtn.Enable = "on";
        end

        % Value changed function: CurrencyDropDown
        function CurrencyDropDownValueChanged(app, event)
            app.coinDiameters = [];
            app.coinFilters = [];

            if app.CurrencyDropDown.Value == "TWD"
                app.coinDiameters(1) = 30; % 1$
                app.coinDiameters(2) = 36; % 5$
                app.coinDiameters(3) = 45; % 10$
                app.coinDiameters(4) = 50; % 50$
                app.coinType = 4;
                app.unit = "元";
            elseif app.CurrencyDropDown.Value == "USD"
                app.coinDiameters(1) = 30; % dime
                app.coinDiameters(2) = 40; % nickel
                app.coinDiameters(3) = 60; % quarter
                app.coinType = 3;
                app.unit = "cent";
            end
        end

        % Button pushed function: SwitchImageBtn
        function SwitchImageBtnButtonPushed(app, event)
            app.n = app.n + 1;
            app.n = mod(app.n, 4);

            hold(app.DetectResultImage, "off");

            switch app.n
                case 0 % Result
                    app.Show_image(app.DetectResultImage, app.img);
                    hold(app.DetectResultImage, "on");
                    for i = 1:app.coinNum
	                    [~, ~, ~, ~] = app.Plot_and_count_detect_coins(app.centroidCoor(i, 1), ...
                                                                      app.centroidCoor(i, 2), ...
                                                                      app.cls(i));
                    end
                    app.DetectResultImageTitle.Text = "Detect Result";
                case 1 % Mask
                    app.Show_image(app.DetectResultImage, app.msk);
                    app.DetectResultImageTitle.Text = "Otsu";
                case 2 % Dilate Result
                    app.Show_image(app.DetectResultImage, app.mskDil);
                    app.DetectResultImageTitle.Text = "Dilated";
                case 3 % Erode Result
                    app.Show_image(app.DetectResultImage, app.mskDilErd);
                    app.DetectResultImageTitle.Text = "Eroded";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Coin_Detector = uifigure('Visible', 'off');
            app.Coin_Detector.Name = 'MATLAB App';
            app.OriginalImage = uiaxes(app.Coin_Detector);
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            app.OriginalImage.ZTick = [];
            colormap(app.OriginalImage, 'gray')
            app.DetectResultImage = uiaxes(app.Coin_Detector);
            app.DetectResultImage.XTick = [];
            app.DetectResultImage.YTick = [];
            app.DetectResultImage.ZTick = [];
            colormap(app.DetectResultImage, 'gray')
            app.SelectImageBtn = uibutton(app.Coin_Detector, 'push');
            app.SelectImageBtn.ButtonPushedFcn = createCallbackFcn(app, @SelectImageBtnButtonPushed, true);
            app.SelectImageBtn.Text = 'Load';
            app.DetectResultLabel = uilabel(app.Coin_Detector);
            app.DetectResultLabel.Text = 'Total : ';
            app.OriginalImageTitle = uilabel(app.Coin_Detector);
            app.OriginalImageTitle.Text = 'Original Image';
            app.DetectResultImageTitle = uilabel(app.Coin_Detector);
            app.DetectResultImageTitle.Text = 'Detect Result';
            app.CurrencyLabel = uilabel(app.Coin_Detector);
            app.CurrencyLabel.Text = 'Currency';
            app.CurrencyDropDown = uidropdown(app.Coin_Detector);
            app.CurrencyDropDown.Items = {'TWD', 'USD'};
            app.CurrencyDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrencyDropDownValueChanged, true);
            app.CurrencyDropDown.Value = 'TWD';
            app.SwitchImageBtn = uibutton(app.Coin_Detector, 'push');
            app.SwitchImageBtn.ButtonPushedFcn = createCallbackFcn(app, @SwitchImageBtnButtonPushed, true);
            app.SwitchImageBtn.Enable = 'off';
            app.SwitchImageBtn.Text = 'Switch';
            % Show the figure after all components are created
            app.Coin_Detector.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Coin_Detector_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Coin_Detector)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Coin_Detector)
        end
    end
end